classdef blinker_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        DataTab                      matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        TimesincelastblinksLabel     matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        FrequencyofBuzzerHzLabel     matlab.ui.control.Label
        StartStopButton_2            matlab.ui.control.StateButton
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Gauge                        matlab.ui.control.Gauge
        Slider                       matlab.ui.control.Slider
        Label_3                      matlab.ui.control.Label
        CalibrationTab               matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        BlinktwiceinquicksuccessionLabel  matlab.ui.control.Label
        BlinkonceasquicklyaspossibleLabel  matlab.ui.control.Label
        BlinkonceslowlyLabel         matlab.ui.control.Label
        BlinkoncenormallyLabel       matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        StartLoggingButton           matlab.ui.control.Button
        BlinkoncenormallyLabel_2     matlab.ui.control.Label
        BlinkoncenormallyLabel_3     matlab.ui.control.Label
        Pressstartloggingtostartstage1Label  matlab.ui.control.Label
        LoggingDurationsSliderLabel  matlab.ui.control.Label
        LoggingDurationsSlider       matlab.ui.control.Slider
        DothesesequentiallyLabel     matlab.ui.control.Label
        CalibrateButton              matlab.ui.control.Button
        Lamp_3                       matlab.ui.control.Lamp
        YouareatstageLabel           matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        NewPersonButton              matlab.ui.control.Button
        RedoPreviousStageButton      matlab.ui.control.Button
        GameTab                      matlab.ui.container.Tab
        Button                       matlab.ui.control.Button
        Lamp_4                       matlab.ui.control.Lamp
        StartStopButton_3            matlab.ui.control.StateButton
        pressstarttoplayLabel        matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        Helpthecatreachthefinishlinebyblinking12timesLabel  matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        starttime;
        thresh_reading_fall = 8;    %how much reading falls before i check if it is a blink
        check_duration = 35;    %how long should i check blink for
        thresh_time_btw_blinks = 600;   %sound buzzer if i havent blinked for this time *0.01s
        min_peak_prom = 30;    %how prominent is peak for it to count as a blink
        min_peak_dist = 10;    %minimum distance between peaks in checkblink
        min_peak_width = 3;    %fastest blink
        max_peak_width = 30;    %slowest blink
        cali_peak_widths = zeros(1,7);
        cali_peak_proms = zeros(1,7);
        writedata = ['1 ' '0' '200' 'd'];
        catsteps=0;
    end
    
    methods (Access = private)
        
        function [y,readings]=checkblink(app,ardu,prev_readings,blink_between,h,ax)
            readings=prev_readings;
            for i=1:app.check_duration
                if blink_between > app.thresh_time_btw_blinks
                    fwrite(ardu,app.writedata,'uchar');
                    app.Label_3.Text='please blink!';
                else
                    app.Label_3.Text='';
                end
                blink_between=blink_between+1;
                app.Gauge.Value=blink_between/100;
                k=fscanf(ardu);
                readings(i+2)=str2double(k);
                t =  datetime('now') - app.starttime;
                % Add points to animation
                addpoints(h,seconds(t),readings(i+2))
                % Update axes
                ax.XLim = [seconds(t)-15 seconds(t)];
                %datetick('x','keeplimits')
                drawnow limitrate
            end
            
            r_negated=-1*readings;
            
            [pks,locs,w,p] = findpeaks(r_negated,'MinPeakProminence',app.min_peak_prom,'MinPeakDistance',app.min_peak_dist,'MinPeakWidth',app.min_peak_width,'MaxPeakWidth',app.max_peak_width);
            
            if length(pks)==1
                y=1;
            else
                y=0;
            end
            
        end
        
        function [y,readings]=checkblinkgame(app,ardu,prev_readings)
            readings=prev_readings;
            for i=1:app.check_duration
                k=fscanf(ardu);
                readings(i+2)=str2double(k);
            end
            
            r_negated=-1*readings;
            
            [pks,locs,w,p] = findpeaks(r_negated,'MinPeakProminence',app.min_peak_prom,'MinPeakDistance',app.min_peak_dist,'MinPeakWidth',app.min_peak_width,'MaxPeakWidth',app.max_peak_width);
            
            if length(pks)==1
                y=1;
            else
                y=0;
            end
            
        end
        
        function k=movingavg(app, x, num_points)
            if length(x)<=num_points
                k=sum(x)/length(x);
            else
                lastelements=x(end-num_points+1:end);
                k=sum(lastelements)/num_points;
            end
        end
    end

    methods (Access = private)

        % Value changed function: StartStopButton_2
        function StartStopButton_2ValueChanged(app, event)
            value = app.StartStopButton_2.Value;
            if value == 1
                cla(app.UIAxes);
                ardu=serial('COM3','Baudrate',9600);
                fopen(ardu);
                k1=fscanf(ardu);        %throw away the first sample
                
                %initialise first 2 readings in y and first 2 values for moving average
                k2=fscanf(ardu);
                y(1)=str2double(k2);
                current_avg(1)=y(1);
                k3=fscanf(ardu);
                y(2)=str2double(k3);
                current_avg(2)=(y(2)+y(1))/2;
                
                blink_between=0; i=0;
                
                h=animatedline(app.UIAxes);
                ax=app.UIAxes;
                %ax.YLim = [0 1024];
                app.starttime = datetime('now');
                app.Lamp.Color=[0 1 0];
                while value == 1
                    i=i+1;      %update index
                    
                    if blink_between > app.thresh_time_btw_blinks     %sound buzzer if you havent blinked for some time
                        fwrite(ardu,app.writedata,'uchar');
                        app.Label_3.Text='please blink!';
                    else
                        app.Label_3.Text='';
                    end
                    
                    blink_between=blink_between+1;      %count how long you havent blinked
                    app.Gauge.Value=blink_between/100;
                    k=fscanf(ardu);     %collect data from arduino
                    y(i+1)=str2double(k);
                    
                    t =  datetime('now')-app.starttime;
                    addpoints(h,seconds(t),y(i+1))      %add points to animation
                    ax.XLim = [seconds(t)-15 seconds(t)];       %update axes
                    %datetick('x','keeplimits')
                    drawnow limitrate     %consider drawnow limitrate
                    
                    if current_avg(i)-y(i+1) > app.thresh_reading_fall    %set threshold for how much reading has to have fallen before you check whether it is a blink
                        prev_readings=[y(i) y(i+1)];
                        [blink,readings]=checkblink(app,ardu,prev_readings,blink_between,h,ax);
                        y=[y readings(3:end)];      %append readings obtained during check to y vector
                        i=i+app.check_duration;     %update index
                        if blink==1
                            app.Label.Text='you blinked';
                            app.Label_2.Text=num2str(blink_between);        %display time since last blink
                            blink_between=0;        %reset time between blinks to zero
                        elseif blink==0
                            app.Label.Text='not a blink';
                            blink_between=blink_between+app.check_duration;     %if not a blink, addd the checking time to time you havent blinked
                            app.Label_2.Text=num2str(blink_between);
                        end
                    end
                    current_avg(i+1)=movingavg(app,y,15);      %update moving average
                end
            elseif value == 0
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                app.Lamp.Color=[1 0 0];
            end
        end

        % Button pushed function: StartLoggingButton
        function StartLoggingButtonPushed(app, event)
            cla(app.UIAxes2);
            ardu=serial('COM3','Baudrate',9600);
            fopen(ardu);
            k1=fscanf(ardu);        %throw away the first sample
            
            h=animatedline(app.UIAxes2);
            ax=app.UIAxes2;
            numpoints = app.LoggingDurationsSlider.Value*100;
            ax.XLim = [0 numpoints/100];
            x=linspace(0,numpoints/100,numpoints);
            
            app.Lamp_2.Color=[0 1 0];
            for i=1:numpoints
                k=fscanf(ardu);     %collect data from arduino
                y(i)=str2double(k);
                addpoints(h,x(i),y(i));
                drawnow limitrate
            end
            
            y_negated = -1*y;
            
            stagevalue=app.Label_6.Text;
            switch stagevalue
                case '1'
                    app.RedoPreviousStageButton.Visible='off';
                    [pks,locs,w,p] = findpeaks(y_negated,'MinPeakProminence',20,'MinPeakDistance',10);
                    if length(pks) ~= 2
                        app.Pressstartloggingtostartstage1Label.Text='repeat stage 1 by pressing "start logging"';
                    else
                        peakdist=locs(2)-locs(1);
                        if peakdist < app.check_duration
                            app.min_peak_dist=peakdist;
                        end
                        app.cali_peak_proms(1)=p(1);
                        app.cali_peak_proms(2)=p(2);
                        app.cali_peak_widths(1)=w(1);
                        app.cali_peak_widths(2)=w(2);
                        app.Pressstartloggingtostartstage1Label.Text='move to stage 2';
                        app.Label_6.Text='2';
                        app.RedoPreviousStageButton.Visible='on';
                    end
                case '2'
                    [pks,locs,w,p] = findpeaks(y_negated,'MinPeakProminence',20,'MinPeakDistance',5);
                    if length(pks) ~= 1
                        app.Pressstartloggingtostartstage1Label.Text='repeat stage 2 by pressing "start logging"';
                    else
                        app.cali_peak_proms(3)=p;
                        app.cali_peak_widths(3)=w;
                        app.Pressstartloggingtostartstage1Label.Text='move to stage 3';
                        app.Label_6.Text='3';
                    end
                case '3'
                    [pks,locs,w,p] = findpeaks(y_negated,'MinPeakProminence',20,'MinPeakDistance',5);
                    if length(pks) ~= 1
                        app.Pressstartloggingtostartstage1Label.Text='repeat stage 3 by pressing "start logging"';
                    else
                        app.cali_peak_proms(4)=p;
                        app.cali_peak_widths(4)=w;
                        app.Pressstartloggingtostartstage1Label.Text='move to stage 4';
                        app.Label_6.Text='4';
                    end
                case '4'
                    [pks,locs,w,p] = findpeaks(y_negated,'MinPeakProminence',20,'MinPeakDistance',5);
                    if length(pks) ~= 1
                        app.Pressstartloggingtostartstage1Label.Text='repeat stage 4 by pressing "start logging"';
                    else
                        app.cali_peak_proms(5)=p;
                        app.cali_peak_widths(5)=w;
                        app.Pressstartloggingtostartstage1Label.Text='move to stage 5';
                        app.Label_6.Text='5';
                    end
                case '5'
                    [pks,locs,w,p] = findpeaks(y_negated,'MinPeakProminence',20,'MinPeakDistance',5);
                    if length(pks) ~= 1
                        app.Pressstartloggingtostartstage1Label.Text='repeat stage 5 by pressing "start logging"';
                    else
                        app.cali_peak_proms(6)=p;
                        app.cali_peak_widths(6)=w;
                        app.Pressstartloggingtostartstage1Label.Text='move to stage 6';
                        app.Label_6.Text='6';
                    end
                case '6'
                    [pks,locs,w,p] = findpeaks(y_negated,'MinPeakProminence',20,'MinPeakDistance',5);
                    if length(pks) ~= 1
                        app.Pressstartloggingtostartstage1Label.Text='repeat stage 6 by pressing "start logging"';
                    else
                        app.StartLoggingButton.Visible='off';
                        app.Lamp_2.Visible='off';
                        app.cali_peak_proms(7)=p;
                        app.cali_peak_widths(7)=w;
                        app.Pressstartloggingtostartstage1Label.Text='done! press calibrate';
                        app.Label_6.Text='7';
                        app.CalibrateButton.Visible='on';
                        app.Lamp_3.Color=[1 0 0];
                        app.Lamp_3.Visible='on';
                    end
            end
            fclose(ardu);
            delete(ardu);
            app.Lamp_2.Color=[1 0 0];
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.min_peak_prom = min(app.cali_peak_proms);    %how prominent is peak for it to count as a blink
            app.min_peak_width = min(app.cali_peak_widths);    %fastest blink
            app.max_peak_width = max(app.cali_peak_widths);    %slowest blink
            app.Lamp_3.Color=[0 1 0];
            %app.Pressstartloggingtostartstage1Label.Text=['prom ' num2str(app.min_peak_prom) ' minwidth ' num2str(app.min_peak_width) ' maxwidth ' num2str(app.max_peak_width)];
            app.Pressstartloggingtostartstage1Label.Text='done calibrating! go to game';
            app.NewPersonButton.Visible='on';
            app.RedoPreviousStageButton.Visible='off';
        end

        % Value changed function: StartStopButton_3
        function StartStopButton_3ValueChanged(app, event)
            value = app.StartStopButton_3.Value;
            if value == 1
                ardu=serial('COM3','Baudrate',9600);
                fopen(ardu);
                k1=fscanf(ardu);        %throw away the first sample
                
                %initialise first 2 readings in y and first 2 values for moving average
                k2=fscanf(ardu);
                y(1)=str2double(k2);
                current_avg(1)=y(1);
                k3=fscanf(ardu);
                y(2)=str2double(k3);
                current_avg(2)=(y(2)+y(1))/2;
                
                h=animatedline(app.UIAxes3);
                h.Visible = 'off';
                
                i=0;
                app.Lamp_4.Color=[0 1 0];
                app.pressstarttoplayLabel.Text='start!';
                
                while app.catsteps < 12
                    i=i+1;      %update index
                    
                    k=fscanf(ardu);     %collect data from arduino
                    y(i+1)=str2double(k);
                    addpoints(h,i+1,y(i+1));
                    drawnow limitrate
                    
                    if current_avg(i)-y(i+1) > app.thresh_reading_fall    %set threshold for how much reading has to have fallen before you check whether it is a blink
                        prev_readings=[y(i) y(i+1)];
                        [blink,readings]=checkblinkgame(app,ardu,prev_readings);
                        y=[y readings(3:end)];      %append readings obtained during check to y vector
                        i=i+app.check_duration;     %update index
                        if blink==1
                            app.catsteps=app.catsteps+1;
                            %make cat walk
                            current_pos=app.Button.Position;
                            app.Button.Position=[current_pos(1)+15,current_pos(2),current_pos(3),current_pos(4)];
                            if app.catsteps == 12
                                app.pressstarttoplayLabel.Text='finish!';
                            else
                                app.pressstarttoplayLabel.Text=['baby step: ' num2str(app.catsteps)];
                            end
                        end
                    end
                    current_avg(i+1)=movingavg(app,y,15);      %update moving average
                end
                fclose(ardu);
                delete(ardu);
            elseif value == 0
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                app.Lamp_4.Color=[1 0 0];
                app.pressstarttoplayLabel.Text='press reset to play again';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Button.Position=[36,115,161,152];
            app.pressstarttoplayLabel.Text='press start to play';
            app.catsteps=0;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            freq=num2str(changingValue);
            if strlength(freq) == 3
                app.writedata = ['1 ' '0' freq 'd'];
            elseif strlength(freq) == 4
                app.writedata = ['1 ' freq 'd'];
            end
        end

        % Button pushed function: NewPersonButton
        function NewPersonButtonPushed(app, event)
            app.min_peak_prom = 30;    %set parameters back to default
            app.min_peak_dist = 10;    
            app.min_peak_width = 3;    
            app.max_peak_width = 30;    
            app.Label_6.Text='1';
            cla(app.UIAxes2);
            app.Pressstartloggingtostartstage1Label.Text='data cleared, now calibrate for new person';
            app.CalibrateButton.Visible='off';
            app.Lamp_3.Visible='off';
            app.StartLoggingButton.Visible='on';
            app.Lamp_2.Visible='on';
        end

        % Button pushed function: RedoPreviousStageButton
        function RedoPreviousStageButtonPushed(app, event)
            currentstage=str2double(app.Label_6.Text);
            if currentstage > 1
                prevstage=currentstage-1;
                app.Label_6.Text=num2str(prevstage);
                app.Pressstartloggingtostartstage1Label.Text='now redo the stage displayed by pressing start logging';
                if currentstage == 7
                    app.StartLoggingButton.Visible='on';
                    app.Lamp_2.Visible='on';
                    app.CalibrateButton.Visible = 'off';
                    app.Lamp_3.Visible='off';
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            freq=num2str(value);
            if strlength(freq) == 3
                app.writedata = ['1 ' '0' freq 'd'];
            elseif strlength(freq) == 4
                app.writedata = ['1 ' freq 'd'];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UIAxes = uiaxes(app.DataTab);
            title(app.UIAxes, 'IR Sensor Data')
            xlabel(app.UIAxes, 'Time/s')
            ylabel(app.UIAxes, 'IR Sensor Reading')
            app.TimesincelastblinksLabel = uilabel(app.DataTab);
            app.TimesincelastblinksLabel.Text = 'Time since last blink (s):';
            app.Lamp = uilamp(app.DataTab);
            app.FrequencyofBuzzerHzLabel = uilabel(app.DataTab);
            app.FrequencyofBuzzerHzLabel.Text = 'Frequency of Buzzer (Hz):';
            app.StartStopButton_2 = uibutton(app.DataTab, 'state');
            app.StartStopButton_2.ValueChangedFcn = createCallbackFcn(app, @StartStopButton_2ValueChanged, true);
            app.StartStopButton_2.Text = 'Start/Stop';
            app.Label = uilabel(app.DataTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.DataTab);
            app.Label_2.Text = '';
            app.Gauge = uigauge(app.DataTab, 'circular');
            app.Gauge.Limits = [0 6];
            app.Slider = uislider(app.DataTab);
            app.Slider.Limits = [200 2000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 200;
            app.Label_3 = uilabel(app.DataTab);
            app.Label_3.Text = '';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.UIAxes2 = uiaxes(app.CalibrationTab);
            title(app.UIAxes2, 'IR Sensor Data')
            xlabel(app.UIAxes2, 'Time/s')
            ylabel(app.UIAxes2, 'IR Sensor Reading')
            app.BlinktwiceinquicksuccessionLabel = uilabel(app.CalibrationTab);
            app.BlinktwiceinquicksuccessionLabel.Text = '1. Blink twice in quick succession';
            app.BlinkonceasquicklyaspossibleLabel = uilabel(app.CalibrationTab);
            app.BlinkonceasquicklyaspossibleLabel.Text = '2. Blink once as quickly as possible';
            app.BlinkonceslowlyLabel = uilabel(app.CalibrationTab);
            app.BlinkonceslowlyLabel.Text = '3. Blink once slowly';
            app.BlinkoncenormallyLabel = uilabel(app.CalibrationTab);
            app.BlinkoncenormallyLabel.Text = '4. Blink once normally';
            app.Lamp_2 = uilamp(app.CalibrationTab);
            app.StartLoggingButton = uibutton(app.CalibrationTab, 'push');
            app.StartLoggingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLoggingButtonPushed, true);
            app.StartLoggingButton.Text = 'Start Logging';
            app.BlinkoncenormallyLabel_2 = uilabel(app.CalibrationTab);
            app.BlinkoncenormallyLabel_2.Text = '5. Blink once normally';
            app.BlinkoncenormallyLabel_3 = uilabel(app.CalibrationTab);
            app.BlinkoncenormallyLabel_3.Text = '6. Blink once normally';
            app.Pressstartloggingtostartstage1Label = uilabel(app.CalibrationTab);
            app.Pressstartloggingtostartstage1Label.Text = 'Press "start logging" to start stage 1';
            app.LoggingDurationsSliderLabel = uilabel(app.CalibrationTab);
            app.LoggingDurationsSliderLabel.Text = 'Logging Duration (s)';
            app.LoggingDurationsSlider = uislider(app.CalibrationTab);
            app.LoggingDurationsSlider.Limits = [1.5 3];
            app.LoggingDurationsSlider.Value = 1.5;
            app.DothesesequentiallyLabel = uilabel(app.CalibrationTab);
            app.DothesesequentiallyLabel.Text = 'Do these sequentially:';
            app.CalibrateButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Visible = 'off';
            app.CalibrateButton.Text = 'Calibrate!';
            app.Lamp_3 = uilamp(app.CalibrationTab);
            app.Lamp_3.Visible = 'off';
            app.YouareatstageLabel = uilabel(app.CalibrationTab);
            app.YouareatstageLabel.Text = 'You are at stage';
            app.Label_6 = uilabel(app.CalibrationTab);
            app.Label_6.Text = '1';
            app.NewPersonButton = uibutton(app.CalibrationTab, 'push');
            app.NewPersonButton.ButtonPushedFcn = createCallbackFcn(app, @NewPersonButtonPushed, true);
            app.NewPersonButton.Visible = 'off';
            app.NewPersonButton.Text = 'New Person';
            app.RedoPreviousStageButton = uibutton(app.CalibrationTab, 'push');
            app.RedoPreviousStageButton.ButtonPushedFcn = createCallbackFcn(app, @RedoPreviousStageButtonPushed, true);
            app.RedoPreviousStageButton.Visible = 'off';
            app.RedoPreviousStageButton.Text = 'Redo Previous Stage';
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.Button = uibutton(app.GameTab, 'push');
            app.Button.Icon = 'cat.png';
            app.Button.Text = '';
            app.Lamp_4 = uilamp(app.GameTab);
            app.StartStopButton_3 = uibutton(app.GameTab, 'state');
            app.StartStopButton_3.ValueChangedFcn = createCallbackFcn(app, @StartStopButton_3ValueChanged, true);
            app.StartStopButton_3.Text = 'Start/Stop';
            app.pressstarttoplayLabel = uilabel(app.GameTab);
            app.pressstarttoplayLabel.Text = 'press start to play';
            app.ResetButton = uibutton(app.GameTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Helpthecatreachthefinishlinebyblinking12timesLabel = uilabel(app.GameTab);
            app.Helpthecatreachthefinishlinebyblinking12timesLabel.Text = 'Help the cat reach the finish line by blinking 12 times!';
            app.UIAxes3 = uiaxes(app.GameTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = blinker_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end